classdef boundaryconditionapplier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MeshTab                  matlab.ui.container.Tab
        SelectFIleButton         matlab.ui.control.Button
        BrowseFileButton         matlab.ui.control.Button
        FileEditField            matlab.ui.control.EditField
        FileEditFieldLabel       matlab.ui.control.Label
        BCsTab                   matlab.ui.container.Tab
        SetButton_2              matlab.ui.control.Button
        ScaleZEditField          matlab.ui.control.NumericEditField
        ScaleZEditFieldLabel     matlab.ui.control.Label
        ScaleYEditField          matlab.ui.control.NumericEditField
        ScaleYEditFieldLabel     matlab.ui.control.Label
        ScaleXEditField          matlab.ui.control.NumericEditField
        ScaleXEditFieldLabel     matlab.ui.control.Label
        RotationZEditField       matlab.ui.control.NumericEditField
        RotationZEditFieldLabel  matlab.ui.control.Label
        RotationYEditField       matlab.ui.control.NumericEditField
        RotationYEditFieldLabel  matlab.ui.control.Label
        RotationXEditField       matlab.ui.control.NumericEditField
        RotationXEditFieldLabel  matlab.ui.control.Label
        CentreZEditField         matlab.ui.control.NumericEditField
        CentreZEditFieldLabel    matlab.ui.control.Label
        CentreYEditField         matlab.ui.control.NumericEditField
        CentreYEditFieldLabel    matlab.ui.control.Label
        CentreXEditField         matlab.ui.control.NumericEditField
        CentreXEditFieldLabel    matlab.ui.control.Label
        ChangeButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        nodes = -1;
        elements = -1;
        plane = -1;
        planenormal = -1;
    end
    
    methods (Access = private)
        
        function plotmesh(~, ax, nodes, elements)
            hold(ax, 'on');
            axis(ax, [0, 1, 0, 1, 0, 1]);
            daspect(ax, [1, 1, 1]);
            view(ax, 45, 45);
            
            %% PLOT QUADS
            for i=1:size(elements, 1)
                polygon = zeros(4, 3);
                for j=1:4
                    polygon(j, :) = nodes(elements(i, j), 1:3);
                    %polygon(j, 3) = 0.1 * abs(sin(polygon(j, 1) * 10));
                end
                fill3(ax, polygon(:, 1), polygon(:, 2), polygon(:, 3), 'r');
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseFileButton
        function BrowseFileButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file, path] = uigetfile('*.msh');
            delete(f);
            %figure(app.UIFigure);
            app.FileEditField.Value = strcat(path, file);
        end

        % Button pushed function: SelectFIleButton
        function SelectFIleButtonPushed(app, event)
            [app.nodes, app.elements] = processmeshfile(app.FileEditField.Value);
            
            plotmesh(app, app.UIAxes, app.nodes, app.elements);
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            if(app.plane ~= -1)
                delete(app.plane);
                delete(app.planenormal);
            end
            x = app.CentreXEditField.Value;
            y = app.CentreYEditField.Value;
            z = app.CentreZEditField.Value;
            
            rotx = app.RotationXEditField.Value;
            roty = app.RotationYEditField.Value;
            rotz = app.RotationZEditField.Value;
            
            scalex = app.ScaleXEditField.Value;
            scaley = app.ScaleYEditField.Value;
            scalez = app.ScaleZEditField.Value;
            
            Rx = [1, 0, 0; 0, cosd(rotx), -sind(rotx);0, sind(rotx), cosd(rotx)];
            Ry = [cosd(roty), 0, sind(roty); 0, 1, 0;-sind(roty), 0, cosd(roty)];
            Rz = [cosd(rotz), -sind(rotz), 0; sind(rotz), cosd(rotz), 0;0, 0, 1];
            
            X = [-scalex/2, scalex/2, scalex/2, -scalex/2];
            Y = [-scaley/2, -scaley/2, scaley/2, scaley/2];
            Z = [0, 0, 0, 0];
            R = Rx * Ry * Rz;
            
            XYZ = [X; Y; Z];
            
            up = [0; 0; 0.25];
            planenormalvec = R * up;
            app.planenormal = quiver3(app.UIAxes, x, y, z, planenormalvec(1), planenormalvec(2), planenormalvec(3), 'g');
            
            for i=1:4
                temp = R * XYZ(:, i);
                X(i) = temp(1);
                Y(i) = temp(2);
                Z(i) = temp(3);
            end
            X = X + [x, x, x, x];
            Y = Y + [y, y, y, y];
            Z = Z + [z, z, z, z];
            
            
            app.plane = fill3(app.UIAxes, X, Y, Z, 'b');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MeshTab = uitab(app.TabGroup);
            app.MeshTab.Title = 'Mesh';
            app.FileEditFieldLabel = uilabel(app.MeshTab);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.MeshTab, 'text');
            app.BrowseFileButton = uibutton(app.MeshTab, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButtonPushed, true);
            app.BrowseFileButton.Text = 'Browse File';
            app.SelectFIleButton = uibutton(app.MeshTab, 'push');
            app.SelectFIleButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFIleButtonPushed, true);
            app.SelectFIleButton.Text = 'Select FIle';
            app.BCsTab = uitab(app.TabGroup);
            app.BCsTab.Title = 'BCs';
            app.UIAxes = uiaxes(app.BCsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ChangeButton = uibutton(app.BCsTab, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.CentreXEditFieldLabel = uilabel(app.BCsTab);
            app.CentreXEditFieldLabel.Text = 'Centre X';
            app.CentreXEditField = uieditfield(app.BCsTab, 'numeric');
            app.CentreXEditField.Value = 0.5;
            app.CentreYEditFieldLabel = uilabel(app.BCsTab);
            app.CentreYEditFieldLabel.Text = 'Centre Y';
            app.CentreYEditField = uieditfield(app.BCsTab, 'numeric');
            app.CentreYEditField.Value = 0.5;
            app.CentreZEditFieldLabel = uilabel(app.BCsTab);
            app.CentreZEditFieldLabel.Text = 'Centre Z';
            app.CentreZEditField = uieditfield(app.BCsTab, 'numeric');
            app.CentreZEditField.Value = 0.5;
            app.RotationXEditFieldLabel = uilabel(app.BCsTab);
            app.RotationXEditFieldLabel.Text = 'Rotation X';
            app.RotationXEditField = uieditfield(app.BCsTab, 'numeric');
            app.RotationYEditFieldLabel = uilabel(app.BCsTab);
            app.RotationYEditFieldLabel.Text = 'Rotation Y';
            app.RotationYEditField = uieditfield(app.BCsTab, 'numeric');
            app.RotationZEditFieldLabel = uilabel(app.BCsTab);
            app.RotationZEditFieldLabel.Text = 'Rotation Z';
            app.RotationZEditField = uieditfield(app.BCsTab, 'numeric');
            app.ScaleXEditFieldLabel = uilabel(app.BCsTab);
            app.ScaleXEditFieldLabel.Text = 'Scale X';
            app.ScaleXEditField = uieditfield(app.BCsTab, 'numeric');
            app.ScaleXEditField.Value = 1;
            app.ScaleYEditFieldLabel = uilabel(app.BCsTab);
            app.ScaleYEditFieldLabel.Text = 'Scale Y';
            app.ScaleYEditField = uieditfield(app.BCsTab, 'numeric');
            app.ScaleYEditField.Value = 1;
            app.ScaleZEditFieldLabel = uilabel(app.BCsTab);
            app.ScaleZEditFieldLabel.Text = 'Scale Z';
            app.ScaleZEditField = uieditfield(app.BCsTab, 'numeric');
            app.ScaleZEditField.Value = 1;
            app.SetButton_2 = uibutton(app.BCsTab, 'push');
            app.SetButton_2.Text = 'Set';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = boundaryconditionapplier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end